classdef AudioDpcmClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioDPCMClientUIFigure         matlab.ui.Figure
        AudioSignalDPCMLabel            matlab.ui.control.Label
        GoBackButton                    matlab.ui.control.Button
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditField              matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        TransmitDataButton              matlab.ui.control.Button
        QuantizationBitsDropDownLabel   matlab.ui.control.Label
        QuantizationBitsDropDown        matlab.ui.control.DropDown
        Switch                          matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        OptimalLabel                    matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        SketchOriginalButton_3          matlab.ui.control.Button
        SketchQuantizedButton_3         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SamplingFrequencyDropDownLabel  matlab.ui.control.Label
        SamplingFrequencyDropDown       matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        NumberofBitsDropDownLabel       matlab.ui.control.Label
        NumberofBitsDropDown            matlab.ui.control.DropDown
        AudioLengthLabel                matlab.ui.control.Label
        AudioLengthEditField            matlab.ui.control.NumericEditField
        PlayOriginalButton              matlab.ui.control.Button
        PlayQuantizedButton             matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        DataSizeLabel                   matlab.ui.control.Label
        QerrorLabel                     matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            global opt;
            global m;
            global mq;
            global time;
            global data;
            global fs;
            global len;
            o=app.QuantizationBitsDropDown.Value;
            samplingfrequency=app.SamplingFrequencyDropDown.Value;
            numOfBits=app.NumberofBitsDropDown.Value;
            channel=app.ChannelDropDown.Value;
            order=app.OrderEditField.Value;
            
            if(order==0)
                order=1;
            end
            
            switch o
                case "2"
                    n=2;
                case "4"
                    n=4;
                case "8"
                    n=8;
                case "16"
                    n=16;
            end
            
            switch samplingfrequency
                case "8000"
                    fs=8000;
                case "11025"
                    fs=11025;
                case "22050"
                    fs=22050;
                case "44100"
                    fs=44100;
                case "48000"
                    fs=48000;
                case "96000"
                    fs=96000;
            end
            
            switch numOfBits
                case "8"
                    recbits=8;
                case "16"
                    recbits=16;
                case "24"
                    recbits=24;
            end
            
            switch channel
                case "Mono"
                    recchan=1;
                case "Stereo"
                    recchan=2;
            end
        
            
            L=2^n;
            
            len=app.AudioLengthEditField.Value;
            time=0:1/fs:(length(m)-1)/fs;

            rec = audiorecorder(fs,recbits,recchan);
            app.Lamp_3.Color="Red";
            recordblocking(rec,len);
            pause(len+1);
            app.Lamp_3.Color="White";
            
            m=getaudiodata(rec);

            Vmin=min(m);
            Vmax=max(m);
            
            if(opt==0)
               codebook=linspace(min(m),max(m),L);
                partition=linspace(min(m),max(m),L-1);
                predictor=[0,1];
                order=2;
            elseif(opt==1)
                primitive_codebook=linspace(Vmin,Vmax,L);
                [predictor,codebook,partition]=dpcmopt(m,order,primitive_codebook);
            
            end
            
                [index,mq]=dpcmenco(m,codebook,partition,predictor);
                Qerror=mean((m-mq').^2);
                app.Label_3.Text=num2str(Qerror);
                codebooksize=length(codebook);
                indexsize=length(index);
                
                data=[len fs codebooksize indexsize index codebook predictor];
                
                DataSize=num2str(length(data));
                app.Label.Text=DataSize;

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            global opt;
            if(value=="Optimal")
                app.Lamp.Color="Green";
                app.Lamp.Enable=true;
                opt=1;
                app.OrderEditField.Enable='on';
                
            elseif(value~="Optimal")
                app.Lamp.Enable=false;
                app.Lamp.Color="White";
                opt=0;
                app.OrderEditField.Enable='off';
            end
        end

        % Button pushed function: SketchOriginalButton_3
        function SketchOriginalButton_3Pushed(app, event)
            global m;
            global time;
            %time=time(1:length(m));
            plot(app.UIAxes, time, m);
        end

        % Button pushed function: SketchQuantizedButton_3
        function SketchQuantizedButton_3Pushed(app, event)
            global mq;
            global time;
            time=time(1:length(mq));
            plot(app.UIAxes,time,mq);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global t;
            ip=app.IPAddressEditField.Value;
            t=tcpip(ip,50000,'NetworkRole',"client","Timeout",90);
            set(t,'OutputBufferSize',8000000);
            t.ByteOrder = 'littleEndian';
            fopen(t);
            app.Lamp_2.Color='Green';
            app.TransmitDataButton.Enable=1;
        end

        % Button pushed function: TransmitDataButton
        function TransmitDataButtonPushed(app, event)
            global t;  
            global data;
            fwrite(t,data,'double');
            app.Label_2.Text='Data Sent!';
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.AudioDPCMClientUIFigure);
            ClientMain
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            global len;
            global m;
            global fs;
            
            player1=audioplayer(m,fs);
            play(player1);
            pause(len);
        end

        % Button pushed function: PlayQuantizedButton
        function PlayQuantizedButtonPushed(app, event)
            global len;
            global mq;
            global fs;
            
            player1=audioplayer(mq,fs);
            play(player1);
            pause(len);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioDPCMClientUIFigure = uifigure;
            app.AudioDPCMClientUIFigure.Name = 'Audio DPCM - Client';
            app.AudioSignalDPCMLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.AudioSignalDPCMLabel.Text = 'Audio Signal-DPCM';
            app.GoBackButton = uibutton(app.AudioDPCMClientUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.IPAddressEditFieldLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.AudioDPCMClientUIFigure, 'text');
            app.ConnectButton = uibutton(app.AudioDPCMClientUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp_2 = uilamp(app.AudioDPCMClientUIFigure);
            app.TransmitDataButton = uibutton(app.AudioDPCMClientUIFigure, 'push');
            app.TransmitDataButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitDataButtonPushed, true);
            app.TransmitDataButton.Enable = 'off';
            app.TransmitDataButton.Text = 'Transmit Data';
            app.QuantizationBitsDropDownLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.QuantizationBitsDropDownLabel.Text = {'Quantization'; 'Bits'};
            app.QuantizationBitsDropDown = uidropdown(app.AudioDPCMClientUIFigure);
            app.QuantizationBitsDropDown.Items = {'2', '4', '8', '16'};
            app.QuantizationBitsDropDown.Value = '2';
            app.Switch = uiswitch(app.AudioDPCMClientUIFigure, 'slider');
            app.Switch.Items = {'', 'Optimal'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '';
            app.Lamp = uilamp(app.AudioDPCMClientUIFigure);
            app.Lamp.Enable = 'off';
            app.OptimalLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.OptimalLabel.Text = 'Optimal';
            app.RecordButton = uibutton(app.AudioDPCMClientUIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SketchOriginalButton_3 = uibutton(app.AudioDPCMClientUIFigure, 'push');
            app.SketchOriginalButton_3.ButtonPushedFcn = createCallbackFcn(app, @SketchOriginalButton_3Pushed, true);
            app.SketchOriginalButton_3.Text = 'Sketch Original';
            app.SketchQuantizedButton_3 = uibutton(app.AudioDPCMClientUIFigure, 'push');
            app.SketchQuantizedButton_3.ButtonPushedFcn = createCallbackFcn(app, @SketchQuantizedButton_3Pushed, true);
            app.SketchQuantizedButton_3.Text = 'Sketch Quantized';
            app.UIAxes = uiaxes(app.AudioDPCMClientUIFigure);
            app.Label = uilabel(app.AudioDPCMClientUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.AudioDPCMClientUIFigure);
            app.Label_2.Text = '';
            app.SamplingFrequencyDropDownLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.SamplingFrequencyDropDownLabel.Text = {'Sampling'; 'Frequency'};
            app.SamplingFrequencyDropDown = uidropdown(app.AudioDPCMClientUIFigure);
            app.SamplingFrequencyDropDown.Items = {'8000', '11025', '22050', '44100', '48000', '96000'};
            app.SamplingFrequencyDropDown.Value = '8000';
            app.ChannelDropDownLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.AudioDPCMClientUIFigure);
            app.ChannelDropDown.Items = {'Mono', 'Stereo'};
            app.ChannelDropDown.Value = 'Mono';
            app.NumberofBitsDropDownLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.NumberofBitsDropDownLabel.Text = {'Number'; 'of Bits'};
            app.NumberofBitsDropDown = uidropdown(app.AudioDPCMClientUIFigure);
            app.NumberofBitsDropDown.Items = {'8', '16', '24'};
            app.NumberofBitsDropDown.Value = '8';
            app.AudioLengthLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.AudioLengthLabel.Text = 'Audio Length';
            app.AudioLengthEditField = uieditfield(app.AudioDPCMClientUIFigure, 'numeric');
            app.PlayOriginalButton = uibutton(app.AudioDPCMClientUIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayQuantizedButton = uibutton(app.AudioDPCMClientUIFigure, 'push');
            app.PlayQuantizedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayQuantizedButtonPushed, true);
            app.PlayQuantizedButton.Text = 'Play Quantized';
            app.Lamp_3 = uilamp(app.AudioDPCMClientUIFigure);
            app.OrderEditFieldLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.OrderEditFieldLabel.Enable = 'off';
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.AudioDPCMClientUIFigure, 'numeric');
            app.OrderEditField.Enable = 'off';
            app.DataSizeLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.DataSizeLabel.Text = 'Data Size:';
            app.QerrorLabel = uilabel(app.AudioDPCMClientUIFigure);
            app.QerrorLabel.Text = 'Qerror: ';
            app.Label_3 = uilabel(app.AudioDPCMClientUIFigure);
            app.Label_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AudioDpcmClient

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioDPCMClientUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioDPCMClientUIFigure)
        end
    end
end