classdef AudioDpcmServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioDPCMServerUIFigure  matlab.ui.Figure
        AudioSignalDPCMLabel     matlab.ui.control.Label
        GoBackButton             matlab.ui.control.Button
        GoOnlineButton           matlab.ui.control.Button
        Lamp_2                   matlab.ui.control.Lamp
        ReceiveDataButton        matlab.ui.control.Button
        SketchAudioButton        matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        Label                    matlab.ui.control.Label
        DataSizeEditFieldLabel   matlab.ui.control.Label
        DataSizeEditField        matlab.ui.control.NumericEditField
        PlayAudioButton          matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SketchAudioButton
        function SketchAudioButtonPushed(app, event)
            global mreconstructed;
            global time;
            time=time(1:length(mreconstructed));
            plot(app.UIAxes, time, mreconstructed);
        end

        % Button pushed function: GoOnlineButton
        function GoOnlineButtonPushed(app, event)
            global t;
            
            t=tcpip('0.0.0.0',50000,'NetworkRole','server','Timeout',90);
            set(t,'InputBufferSize',8000000);
            t.ByteOrder='littleEndian';
            fopen(t);
            app.ReceiveDataButton.Enable=1;
            app.Lamp_2.Color='Green';
        end

        % Button pushed function: ReceiveDataButton
        function ReceiveDataButtonPushed(app, event)
            global t;  
            global time;
            global mreconstructed;
            global len;
            global fs;
            ds=app.DataSizeEditField.Value;
            message=fread(t,ds,'double');
       
            len=message(1);
            fs=message(2);
            codebooksize=message(3);
            indexsize=message(4);
            message1=message(5:end);
            index=message1(1:indexsize);
            codebook=message1(1+indexsize:1+indexsize+codebooksize);
            predictor=message1(1+indexsize+codebooksize:end);
         
            mreconstructed=dpcmdeco(index,codebook,predictor);

            time=0:1/fs:(length(mreconstructed)/fs-1/fs);
           
            fclose(t);
            app.Label.Text='Message Received!';
            
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.AudioDPCMServerUIFigure);
            ServerMain
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            global len;
            global mreconstructed;
            global fs;
            
            player1=audioplayer(mreconstructed,fs);
            play(player1);
            pause(len);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioDPCMServerUIFigure = uifigure;
            app.AudioDPCMServerUIFigure.Name = 'Audio DPCM - Server';
            app.AudioSignalDPCMLabel = uilabel(app.AudioDPCMServerUIFigure);
            app.AudioSignalDPCMLabel.Text = 'Audio Signal-DPCM';
            app.GoBackButton = uibutton(app.AudioDPCMServerUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.GoOnlineButton = uibutton(app.AudioDPCMServerUIFigure, 'push');
            app.GoOnlineButton.ButtonPushedFcn = createCallbackFcn(app, @GoOnlineButtonPushed, true);
            app.GoOnlineButton.Text = 'Go Online';
            app.Lamp_2 = uilamp(app.AudioDPCMServerUIFigure);
            app.ReceiveDataButton = uibutton(app.AudioDPCMServerUIFigure, 'push');
            app.ReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveDataButtonPushed, true);
            app.ReceiveDataButton.Enable = 'off';
            app.ReceiveDataButton.Text = 'Receive Data';
            app.SketchAudioButton = uibutton(app.AudioDPCMServerUIFigure, 'push');
            app.SketchAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SketchAudioButtonPushed, true);
            app.SketchAudioButton.Text = 'Sketch Audio';
            app.UIAxes = uiaxes(app.AudioDPCMServerUIFigure);
            app.Label = uilabel(app.AudioDPCMServerUIFigure);
            app.Label.Text = '';
            app.DataSizeEditFieldLabel = uilabel(app.AudioDPCMServerUIFigure);
            app.DataSizeEditFieldLabel.Text = 'Data Size';
            app.DataSizeEditField = uieditfield(app.AudioDPCMServerUIFigure, 'numeric');
            app.PlayAudioButton = uibutton(app.AudioDPCMServerUIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AudioDpcmServer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioDPCMServerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioDPCMServerUIFigure)
        end
    end
end