classdef AudioPcmClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioPCMClientUIFigure          matlab.ui.Figure
        AudioSignalPCMLabel             matlab.ui.control.Label
        GoBackButton                    matlab.ui.control.Button
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditField              matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        TransmitDataButton              matlab.ui.control.Button
        QuantizationBitsDropDownLabel   matlab.ui.control.Label
        QuantizationBitsDropDown        matlab.ui.control.DropDown
        Switch                          matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        OptimalLabel                    matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        SketchOriginalButton_3          matlab.ui.control.Button
        SketchQuantizedButton_3         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SamplingFrequencyDropDownLabel  matlab.ui.control.Label
        SamplingFrequencyDropDown       matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        NumberofBitsDropDownLabel       matlab.ui.control.Label
        NumberofBitsDropDown            matlab.ui.control.DropDown
        AudioLengthLabel                matlab.ui.control.Label
        AudioLengthEditField            matlab.ui.control.NumericEditField
        PlayOriginalButton              matlab.ui.control.Button
        PlayQuantizedButton             matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        DataSizeLabel                   matlab.ui.control.Label
        QerrorLabel                     matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            global opt;
            global m;
            global mq;
            global time;
            global data;
            global fs;
            global len;
            o=app.QuantizationBitsDropDown.Value;
            samplingfrequency=app.SamplingFrequencyDropDown.Value;
            numOfBits=app.NumberofBitsDropDown.Value;
            channel=app.ChannelDropDown.Value;
            
            
            switch o
                case "2"
                    n=2;
                case "4"
                    n=4;
                case "8"
                    n=8;
                case "16"
                    n=16;
            end
            
            switch samplingfrequency
                case "8000"
                    fs=8000;
                case "11025"
                    fs=11025;
                case "22050"
                    fs=22050;
                case "44100"
                    fs=44100;
                case "48000"
                    fs=48000;
                case "96000"
                    fs=96000;
            end
            
            switch numOfBits
                case "8"
                    recbits=8;
                case "16"
                    recbits=16;
                case "24"
                    recbits=24;
            end
            
            switch channel
                case "Mono"
                    recchan=1;
                case "Stereo"
                    recchan=2;
            end
        
            
            L=2^n;
            
            len=app.AudioLengthEditField.Value;
            time=0:1/fs:(length(m)-1)/fs;

            rec = audiorecorder(fs,recbits,recchan);
            app.Lamp_3.Color="Red";
            recordblocking(rec,len);
            pause(len+1);
            app.Lamp_3.Color="White";
            
            m=getaudiodata(rec);

            Vmin=min(m);
            Vmax=max(m);
            stepsize=((Vmax-Vmin)/L);
            
            if(opt==1)
                primitive_codebook=Vmin-stepsize/2:stepsize:Vmax+stepsize/2;
                [partition,codebook]=lloyds(m,primitive_codebook);
            elseif(opt==0)
                partition=Vmin:stepsize:Vmax;
                codebook=Vmin-stepsize/2:stepsize:Vmax+stepsize/2;
            end
            
                [index,mq]=quantiz(m,partition,codebook);
                Qerror=mean((m-mq').^2);
                app.Label_3.Text=num2str(Qerror);                
                i_non_zero=find(index~=0);
                index(i_non_zero)=index(i_non_zero)-1;
                b=de2bi(index,'left-msb');
                
                n1=length(b(1,:));
                transmitted=reshape(b,[1 length(index)*n1]);
                data=[n1 stepsize len fs Vmin length(index) transmitted];
                DataSize=num2str(length(data));
                app.Label.Text=DataSize;

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            global opt;
            if(value=="Optimal")
                app.Lamp.Color="Green";
                app.Lamp.Enable=true;
                opt=1;
                
            elseif(value~="Optimal")
                app.Lamp.Enable=false;
                app.Lamp.Color="White";
                opt=0;
            end
        end

        % Button pushed function: SketchOriginalButton_3
        function SketchOriginalButton_3Pushed(app, event)
global m;
global time;

% Check if 'm' is empty and handle the case
if isempty(m)
    app.Label.Text = 'Variable m is empty. Cannot plot data.';
    return;
end

% Ensure 'time' is not empty
if isempty(time)
    app.Label.Text = 'Variable time is empty. Cannot plot data.';
    return;
end

% Ensure both variables have data and are of compatible lengths
minLength = min(length(m), length(time));
if minLength == 0
    app.Label.Text = 'Insufficient data to plot.';
    return;
end

% Truncate or match lengths
m = m(1:minLength);
time = time(1:minLength);

% Plot the data
plot(app.UIAxes, time, m);
app.Label.Text = 'Data plotted successfully!';


        end

        % Button pushed function: SketchQuantizedButton_3
        function SketchQuantizedButton_3Pushed(app, event)

            global mq;
            global time;
            
            if isempty(mq)
                error('Variable m is empty.');
            else
                
                minLength = min(length(mq), length(time));
                
                mq = mq(1:minLength);
                time = time(1:minLength);
                
                
                plot(app.UIAxes, time, mq);
            end

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global t;
            ip=app.IPAddressEditField.Value;
            t=tcpip(ip,50000,'NetworkRole',"client","Timeout",90);
            set(t,'OutputBufferSize',8000000);
            t.ByteOrder = 'littleEndian';
            fopen(t);
            app.Lamp_2.Color='Green';
            app.TransmitDataButton.Enable=1;
        end

        % Button pushed function: TransmitDataButton
        function TransmitDataButtonPushed(app, event)
 global t;
global data;

% Close the object if it is open
if strcmp(t.Status, 'open')
    fclose(t);
end

% Increase the OutputBufferSize to accommodate the data
requiredBufferSize = numel(data) * 8; % 8 bytes per double
t.OutputBufferSize = requiredBufferSize;

% Reopen the object
fopen(t);

% Now write the data
fwrite(t, data, 'double');
app.Label_2.Text = 'Data Sent!';


        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.AudioPCMClientUIFigure);
            ClientMain
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            global len;
            global m;
            global fs;
            
            player1=audioplayer(m,fs);
            play(player1);
            pause(len);
        end

        % Button pushed function: PlayQuantizedButton
        function PlayQuantizedButtonPushed(app, event)
            global len;
            global mq;
            global fs;
            
            player1=audioplayer(mq,fs);
            play(player1);
            pause(len);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioPCMClientUIFigure = uifigure;
            app.AudioPCMClientUIFigure.Name = 'Audio PCM - Client';
            app.AudioSignalPCMLabel = uilabel(app.AudioPCMClientUIFigure);
            app.AudioSignalPCMLabel.Text = 'Audio Signal- PCM';
            app.GoBackButton = uibutton(app.AudioPCMClientUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.IPAddressEditFieldLabel = uilabel(app.AudioPCMClientUIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.AudioPCMClientUIFigure, 'text');
            app.ConnectButton = uibutton(app.AudioPCMClientUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp_2 = uilamp(app.AudioPCMClientUIFigure);
            app.TransmitDataButton = uibutton(app.AudioPCMClientUIFigure, 'push');
            app.TransmitDataButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitDataButtonPushed, true);
            app.TransmitDataButton.Enable = 'off';
            app.TransmitDataButton.Text = 'Transmit Data';
            app.QuantizationBitsDropDownLabel = uilabel(app.AudioPCMClientUIFigure);
            app.QuantizationBitsDropDownLabel.Text = {'Quantization'; 'Bits'};
            app.QuantizationBitsDropDown = uidropdown(app.AudioPCMClientUIFigure);
            app.QuantizationBitsDropDown.Items = {'2', '4', '8', '16'};
            app.QuantizationBitsDropDown.Value = '2';
            app.Switch = uiswitch(app.AudioPCMClientUIFigure, 'slider');
            app.Switch.Items = {'', 'Optimal'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '';
            app.Lamp = uilamp(app.AudioPCMClientUIFigure);
            app.Lamp.Enable = 'off';
            app.OptimalLabel = uilabel(app.AudioPCMClientUIFigure);
            app.OptimalLabel.Text = 'Optimal';
            app.RecordButton = uibutton(app.AudioPCMClientUIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SketchOriginalButton_3 = uibutton(app.AudioPCMClientUIFigure, 'push');
            app.SketchOriginalButton_3.ButtonPushedFcn = createCallbackFcn(app, @SketchOriginalButton_3Pushed, true);
            app.SketchOriginalButton_3.Text = 'Sketch Original';
            app.SketchQuantizedButton_3 = uibutton(app.AudioPCMClientUIFigure, 'push');
            app.SketchQuantizedButton_3.ButtonPushedFcn = createCallbackFcn(app, @SketchQuantizedButton_3Pushed, true);
            app.SketchQuantizedButton_3.Text = 'Sketch Quantized';
            app.UIAxes = uiaxes(app.AudioPCMClientUIFigure);
            app.Label = uilabel(app.AudioPCMClientUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.AudioPCMClientUIFigure);
            app.Label_2.Text = '';
            app.SamplingFrequencyDropDownLabel = uilabel(app.AudioPCMClientUIFigure);
            app.SamplingFrequencyDropDownLabel.Text = {'Sampling'; 'Frequency'};
            app.SamplingFrequencyDropDown = uidropdown(app.AudioPCMClientUIFigure);
            app.SamplingFrequencyDropDown.Items = {'8000', '11025', '22050', '44100', '48000', '96000'};
            app.SamplingFrequencyDropDown.Value = '8000';
            app.ChannelDropDownLabel = uilabel(app.AudioPCMClientUIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.AudioPCMClientUIFigure);
            app.ChannelDropDown.Items = {'Mono', 'Stereo'};
            app.ChannelDropDown.Value = 'Mono';
            app.NumberofBitsDropDownLabel = uilabel(app.AudioPCMClientUIFigure);
            app.NumberofBitsDropDownLabel.Text = {'Number'; 'of Bits'};
            app.NumberofBitsDropDown = uidropdown(app.AudioPCMClientUIFigure);
            app.NumberofBitsDropDown.Items = {'8', '16', '24'};
            app.NumberofBitsDropDown.Value = '8';
            app.AudioLengthLabel = uilabel(app.AudioPCMClientUIFigure);
            app.AudioLengthLabel.Text = 'Audio Length';
            app.AudioLengthEditField = uieditfield(app.AudioPCMClientUIFigure, 'numeric');
            app.PlayOriginalButton = uibutton(app.AudioPCMClientUIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayQuantizedButton = uibutton(app.AudioPCMClientUIFigure, 'push');
            app.PlayQuantizedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayQuantizedButtonPushed, true);
            app.PlayQuantizedButton.Text = 'Play Quantized';
            app.Lamp_3 = uilamp(app.AudioPCMClientUIFigure);
            app.DataSizeLabel = uilabel(app.AudioPCMClientUIFigure);
            app.DataSizeLabel.Text = 'Data Size:';
            app.QerrorLabel = uilabel(app.AudioPCMClientUIFigure);
            app.QerrorLabel.Text = 'Qerror: ';
            app.Label_3 = uilabel(app.AudioPCMClientUIFigure);
            app.Label_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AudioPcmClient

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioPCMClientUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioPCMClientUIFigure)
        end
    end
end