classdef AudioPcmServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioPCMServerUIFigure  matlab.ui.Figure
        AudioSignalPCMLabel     matlab.ui.control.Label
        GoBackButton            matlab.ui.control.Button
        GoOnlineButton          matlab.ui.control.Button
        Lamp_2                  matlab.ui.control.Lamp
        ReceiveDataButton       matlab.ui.control.Button
        SketchAudioButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        DataSizeEditFieldLabel  matlab.ui.control.Label
        DataSizeEditField       matlab.ui.control.NumericEditField
        PlayAudioButton         matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SketchAudioButton
        function SketchAudioButtonPushed(app, event)
            global mreconstructed;
            global time;
            time=time(1:length(mreconstructed));
            plot(app.UIAxes, time, mreconstructed);
        end

        % Button pushed function: GoOnlineButton
        function GoOnlineButtonPushed(app, event)
            global t;
            
            t=tcpip('0.0.0.0',50000,'NetworkRole','server','Timeout',90);
            set(t,'InputBufferSize',8000000);
            t.ByteOrder='littleEndian';
            fopen(t);
            app.ReceiveDataButton.Enable=1;
            app.Lamp_2.Color='Green';
        end

        % Button pushed function: ReceiveDataButton
        function ReceiveDataButtonPushed(app, event)
        global t;
global time;
global mreconstructed;
global len;
global fs;

% Read the data size from the app's field
ds = app.DataSizeEditField.Value;

% Read the incoming message
message = fread(t, ds, 'double');

% Ensure message length is as expected
if length(message) < 7
    app.Label.Text = 'Error: Received message too short!';
    return;
end

% Parse the received message
n = message(1);
stepsize = message(2);
len = message(3);
fs = message(4);
Vmin = message(5);
indexsize = message(6);
transmitted = message(7:end);

% Check if the transmitted data has the expected size
if length(transmitted) ~= n * indexsize
    app.Label.Text = 'Error: Received data size mismatch!';
    return;
end

% Reshape and reconstruct the message
received = reshape(transmitted, [indexsize, n]);
indexr = bi2de(received', 'left-msb'); % Note the transpose
mreconstructed = indexr * stepsize + Vmin + stepsize / 2;
time = 0:1/fs:(length(mreconstructed)/fs - 1/fs);

% Display lengths for debugging
disp(length(mreconstructed));
disp(length(time));

% Close the connection
fclose(t);

app.Label.Text = 'Message Received!';

        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.AudioPCMServerUIFigure);
            ServerMain
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            global len;
            global mreconstructed;
            global fs;  
            player1=audioplayer(mreconstructed,fs);
            play(player1);
            pause(len);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioPCMServerUIFigure = uifigure;
            app.AudioPCMServerUIFigure.Name = 'Audio PCM - Server';
            app.AudioSignalPCMLabel = uilabel(app.AudioPCMServerUIFigure);
            app.AudioSignalPCMLabel.Text = 'Audio Signal-PCM';
            app.GoBackButton = uibutton(app.AudioPCMServerUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.GoOnlineButton = uibutton(app.AudioPCMServerUIFigure, 'push');
            app.GoOnlineButton.ButtonPushedFcn = createCallbackFcn(app, @GoOnlineButtonPushed, true);
            app.GoOnlineButton.Text = 'Go Online';
            app.Lamp_2 = uilamp(app.AudioPCMServerUIFigure);
            app.ReceiveDataButton = uibutton(app.AudioPCMServerUIFigure, 'push');
            app.ReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveDataButtonPushed, true);
            app.ReceiveDataButton.Enable = 'off';
            app.ReceiveDataButton.Text = 'Receive Data';
            app.SketchAudioButton = uibutton(app.AudioPCMServerUIFigure, 'push');
            app.SketchAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SketchAudioButtonPushed, true);
            app.SketchAudioButton.Text = 'Sketch Audio';
            app.UIAxes = uiaxes(app.AudioPCMServerUIFigure);
            app.Label = uilabel(app.AudioPCMServerUIFigure);
            app.Label.Text = '';
            app.DataSizeEditFieldLabel = uilabel(app.AudioPCMServerUIFigure);
            app.DataSizeEditFieldLabel.Text = 'Data Size';
            app.DataSizeEditField = uieditfield(app.AudioPCMServerUIFigure, 'numeric');
            app.PlayAudioButton = uibutton(app.AudioPCMServerUIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AudioPcmServer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioPCMServerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioPCMServerUIFigure)
        end
    end
end