classdef ImageDpcmClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageDPCMClientUIFigure         matlab.ui.Figure
        ImageDPCMLabel                  matlab.ui.control.Label
        GoBackButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        ModulatePhotoButton             matlab.ui.control.Button
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditField              matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        TransmitDataButton              matlab.ui.control.Button
        UploadPhotoButton               matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        QuantizationBitsEditField       matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        DataSizeLabel                   matlab.ui.control.Label
        QuantizationBitsEditFieldLabel  matlab.ui.control.Label
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('camera.jpg','Parent',app.UIAxes_2);

        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(value=="Optimal")
                app.Lamp.Color='Green';
                app.Lamp.Enable=true;
                
            elseif(value~="Optimal")
                app.Lamp.Enable=false;
                app.Lamp.Color="White";
            end
        end

        % Button pushed function: ModulatePhotoButton
        function ModulatePhotoButtonPushed(app, event)
            global img;
            global data;
            order=app.OrderEditField.Value;
            n=app.QuantizationBitsEditField.Value;

            
            img = imresize(img,[480 640]);
                      
            
            red=img(:,:,1);
            green=img(:,:,2);
            blue=img(:,:,3);
            
            L=2^n;
          
            redn=reshape(red,1,307200);
            greenn=reshape(green,1,307200);
            bluen=reshape(blue,1,307200);

            %Create an array to combine the new colors of the image
            allimp = [redn,greenn,bluen];
            allimp=double(allimp);
            %We do type-casting which is converting from unsigned to signed in order to
            %do calculations --> use double
            Vmax=double(max(allimp));
            Vmin=double(min(allimp));

            codebook=double(round(linspace(Vmin,Vmax,L)));
            [predictor,codebook_new,partition]=dpcmopt(allimp,order,codebook);
            [index,mq]=dpcmenco(allimp,codebook_new,partition,predictor);
            
            codebooksize=length(codebook_new);
            predictorsize=length(predictor);
            
            disp(codebooksize);
            disp(predictorsize);
            disp(length(index));
            
            data=[codebooksize predictorsize codebook_new predictor index];
            
            DataSize=num2str(length(data));
            app.Label.Text=DataSize;


        end

        % Button pushed function: UploadPhotoButton
        function UploadPhotoButtonPushed(app, event)
             % Open file selection dialog
             global img;
            [file, path] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.jpeg'}, 'Select an Image');
            
            % Check if the user clicked 'Cancel'
            if isequal(file,0) || isequal(path,0)
                return; % Exit the function without doing anything
            end
        
            % Load the image using the selected file path
            imagePath = fullfile(path, file);
            img = imread(imagePath);
        
            % Display the image on the UI
            imshow(img, 'Parent', app.UIAxes);

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global t;
            global data;
            ip=app.IPAddressEditField.Value;
            t=tcpip(ip,50000,'NetworkRole',"client","Timeout",90);
            set(t,'OutputBufferSize',length(data)*8);
            t.ByteOrder = 'littleEndian';
            fopen(t);
            app.Lamp_2.Color='Green';
            app.TransmitDataButton.Enable=1;
        end

        % Button pushed function: TransmitDataButton
        function TransmitDataButtonPushed(app, event)
            global t;  
            global data;
            fwrite(t,data,'double');
            app.Label_2.Text='Data Sent!';
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.ImageDPCMClientUIFigure);
            ClientMain
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageDPCMClientUIFigure = uifigure;
            app.ImageDPCMClientUIFigure.Name = 'Image DPCM - Client';
            app.ImageDPCMLabel = uilabel(app.ImageDPCMClientUIFigure);
            app.ImageDPCMLabel.Text = 'Image-DPCM';
            app.GoBackButton = uibutton(app.ImageDPCMClientUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.UIAxes = uiaxes(app.ImageDPCMClientUIFigure);
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes_2 = uiaxes(app.ImageDPCMClientUIFigure);
            app.UIAxes_2.ZTick = [0 0.5 1];
            app.ModulatePhotoButton = uibutton(app.ImageDPCMClientUIFigure, 'push');
            app.ModulatePhotoButton.ButtonPushedFcn = createCallbackFcn(app, @ModulatePhotoButtonPushed, true);
            app.ModulatePhotoButton.Text = 'Modulate Photo';
            app.IPAddressEditFieldLabel = uilabel(app.ImageDPCMClientUIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.ImageDPCMClientUIFigure, 'text');
            app.ConnectButton = uibutton(app.ImageDPCMClientUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp_2 = uilamp(app.ImageDPCMClientUIFigure);
            app.TransmitDataButton = uibutton(app.ImageDPCMClientUIFigure, 'push');
            app.TransmitDataButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitDataButtonPushed, true);
            app.TransmitDataButton.Enable = 'off';
            app.TransmitDataButton.Text = 'Transmit Data';
            app.UploadPhotoButton = uibutton(app.ImageDPCMClientUIFigure, 'push');
            app.UploadPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButtonPushed, true);
            app.UploadPhotoButton.Text = 'Upload Photo';
            app.Label = uilabel(app.ImageDPCMClientUIFigure);
            app.Label.Text = '';
            app.QuantizationBitsEditField = uieditfield(app.ImageDPCMClientUIFigure, 'numeric');
            app.Label_2 = uilabel(app.ImageDPCMClientUIFigure);
            app.Label_2.Text = '';
            app.DataSizeLabel = uilabel(app.ImageDPCMClientUIFigure);
            app.DataSizeLabel.Text = 'Data Size:';
            app.QuantizationBitsEditFieldLabel = uilabel(app.ImageDPCMClientUIFigure);
            app.QuantizationBitsEditFieldLabel.Text = {'Quantization'; 'Bits'};
            app.OrderEditFieldLabel = uilabel(app.ImageDPCMClientUIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.ImageDPCMClientUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageDpcmClient

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageDPCMClientUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageDPCMClientUIFigure)
        end
    end
end