classdef ImageDpcmServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageDPCMServerUIFigure  matlab.ui.Figure
        ImageDPCMLabel           matlab.ui.control.Label
        GoBackButton             matlab.ui.control.Button
        GoOnlineButton           matlab.ui.control.Button
        Lamp_2                   matlab.ui.control.Lamp
        ReceiveDataButton        matlab.ui.control.Button
        SketchDataButton         matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        DataSizeEditFieldLabel   matlab.ui.control.Label
        DataSizeEditField        matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: SketchDataButton
        function SketchDataButtonPushed(app, event)
            global imgobtained;
            imshow(imgobtained,'Parent',app.UIAxes);

        end

        % Button pushed function: GoOnlineButton
        function GoOnlineButtonPushed(app, event)
            global t;
            
            t=tcpip('0.0.0.0',50000,'NetworkRole','server','Timeout',90);
            set(t,'InputBufferSize',80000000);
            t.ByteOrder='littleEndian';
            fopen(t);
            app.ReceiveDataButton.Enable=1;
            app.Lamp_2.Color='Green';
        end

        % Button pushed function: ReceiveDataButton
        function ReceiveDataButtonPushed(app, event)
            global t;  
            global imgobtained;
            ds=app.DataSizeEditField.Value;
            m=fread(t,ds,'double');
            message=m';
            
            codebooklength=message(1);
            predictorlength=message(2);
            message1=message(3:end);
            codebook=message1(1:codebooklength);
            predictor=message1(1+codebooklength:1+codebooklength+predictorlength);
            index=message1(1+codebooklength+predictorlength:end);
            
            
            disp(length(codebook));
            disp(length(predictor));
            disp(length(index));
            
            imgreconstructed=dpcmdeco(index,codebook,predictor);
            
            redrecn=imgreconstructed(1:1:307200);
            greenrecn=imgreconstructed(307201:1:307200+307200);
            bluerecn=imgreconstructed(307200+307200+1:1:end);
            
            redrec=reshape(redrecn,480,640);
            greenrec=reshape(greenrecn,480,640);
            bluerec=reshape(bluerecn,480,640);
            
            imgobtained(:,:,1)=uint8(redrec);
            imgobtained(:,:,2)=uint8(greenrec);
            imgobtained(:,:,3)=uint8(bluerec);

            
            fclose(t);
            
            
            app.Label.Text='Message Received!';
            
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.ImageDPCMServerUIFigure);
            ServerMain
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageDPCMServerUIFigure = uifigure;
            app.ImageDPCMServerUIFigure.Name = 'Image DPCM - Server';
            app.ImageDPCMLabel = uilabel(app.ImageDPCMServerUIFigure);
            app.ImageDPCMLabel.Text = 'Image-DPCM';
            app.GoBackButton = uibutton(app.ImageDPCMServerUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.GoOnlineButton = uibutton(app.ImageDPCMServerUIFigure, 'push');
            app.GoOnlineButton.ButtonPushedFcn = createCallbackFcn(app, @GoOnlineButtonPushed, true);
            app.GoOnlineButton.Text = 'Go Online';
            app.Lamp_2 = uilamp(app.ImageDPCMServerUIFigure);
            app.ReceiveDataButton = uibutton(app.ImageDPCMServerUIFigure, 'push');
            app.ReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveDataButtonPushed, true);
            app.ReceiveDataButton.Enable = 'off';
            app.ReceiveDataButton.Text = 'Receive Data';
            app.SketchDataButton = uibutton(app.ImageDPCMServerUIFigure, 'push');
            app.SketchDataButton.ButtonPushedFcn = createCallbackFcn(app, @SketchDataButtonPushed, true);
            app.SketchDataButton.Text = 'Sketch Data';
            app.Label = uilabel(app.ImageDPCMServerUIFigure);
            app.Label.Text = '';
            app.DataSizeEditFieldLabel = uilabel(app.ImageDPCMServerUIFigure);
            app.DataSizeEditFieldLabel.Text = 'Data Size';
            app.DataSizeEditField = uieditfield(app.ImageDPCMServerUIFigure, 'numeric');
            app.UIAxes = uiaxes(app.ImageDPCMServerUIFigure);
            app.UIAxes.ZTick = [0 0.5 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageDpcmServer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageDPCMServerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageDPCMServerUIFigure)
        end
    end
end