classdef ImagePcmClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImagePCMClientUIFigure    matlab.ui.Figure
        ImagePCMLabel             matlab.ui.control.Label
        GoBackButton              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        ModulatePhotoButton       matlab.ui.control.Button
        ShowQuantizedPhotoButton  matlab.ui.control.Button
        IPAddressEditFieldLabel   matlab.ui.control.Label
        IPAddressEditField        matlab.ui.control.EditField
        ConnectButton             matlab.ui.control.Button
        Lamp_2                    matlab.ui.control.Lamp
        TransmitDataButton        matlab.ui.control.Button
        UploadPhotoButton         matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        DataSizeLabel             matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        ShowOriginalPhotoButton   matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('camera.jpg','Parent',app.UIAxes_2);

        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(value=="Optimal")
                app.Lamp.Color='Green';
                app.Lamp.Enable=true;
                
            elseif(value~="Optimal")
                app.Lamp.Enable=false;
                app.Lamp.Color="White";
            end
        end

        % Button pushed function: ModulatePhotoButton
        function ModulatePhotoButtonPushed(app, event)
            global img;
            global imgquantizedn;
            global data;
            global img2;
            
            img = imresize(img,[480 640]);
            img2=img;
            
            red=img(:,:,1);
            green=img(:,:,2);
            blue=img(:,:,3);
            
            n=4;
            L=2^n;
          
%             wred=(length(red(1,:)));
%             lred=(length(red(:,1)));
%             wgreen=(length(green(1,:)));
%             lgreen=(length(green(:,1)));
%             wblue=(length(blue(1,:)));
%             lblue=(length(blue(:,1)));
%             
%             
%             redn=reshape(red,1,lred*wred);
%             greenn=reshape(green,1,lgreen*wgreen);
%             bluen=reshape(blue,1,lblue*wblue);
            
            redn=reshape(red,1,307200);
            greenn=reshape(green,1,307200);
            bluen=reshape(blue,1,307200);

            %Create an array to combine the new colors of the image
            allimp = [redn,greenn,bluen];
            
            %We do type-casting which is converting from unsigned to signed in order to
            %do calculations --> use double
            Vmax=double(max(allimp));
            Vmin=double(min(allimp));
            
%             stepsize=round((Vmax-Vmin)/6);
%             partition=Vmin:stepsize:Vmax;
%             codebook=uint8(Vmin-stepsize/2:stepsize:Vmax+stepsize/2);
%             [index,imgq]=quantiz(allimp,partition,codebook);
%             imgquantized=uint8(imgq);
            
            stepsize=round((Vmax-Vmin)/L);
            codebook=uint8(Vmin-stepsize/2:stepsize:Vmax+stepsize/2);
            [optimal_partition,optimal_codebook]=lloyds(allimp,codebook);
            [index,imgq]=quantiz(allimp,optimal_partition,optimal_codebook);
            imgquantized=uint8(imgq);

            
            redquantized=imgquantized(1:1:length(redn));
            greenquantized=imgquantized(length(redn)+1:1:length(greenn)+length(redn));
            bluequantized=imgquantized(length(redn)+length(greenn)+1:1:length(bluen)+length(greenn)+length(redn));
            
%             redrecon=reshape(redquantized,wred,lred);
%             greenrecon=reshape(greenquantized,wgreen,lgreen);
%             bluerecon=reshape(bluequantized,wblue,lblue);
                        
            redrecon=reshape(redquantized,480,640);
            greenrecon=reshape(greenquantized,480,640);
            bluerecon=reshape(bluequantized,480,640);


            imgquantizedn(:,:,1)=redrecon;
            imgquantizedn(:,:,2)=greenrecon;
            imgquantizedn(:,:,3)=bluerecon;
            
            imgquantizedn=uint8(imgquantizedn);
            
            b=de2bi(index,'left-msb');

            indexsize=length(index);
            
            wb=(length(b(1,:)));

            
            transmitted=reshape(b,[1 length(index)*wb]);
            
            data=[stepsize Vmin n wb indexsize transmitted];           
            DataSize=num2str(length(data));
            app.Label.Text=DataSize;
        end

        % Button pushed function: ShowQuantizedPhotoButton
        function ShowQuantizedPhotoButtonPushed(app, event)
            global imgquantizedn;
            
            imshow(imgquantizedn,'Parent',app.UIAxes);

        end

        % Button pushed function: UploadPhotoButton
        function UploadPhotoButtonPushed(app, event)
             % Open file selection dialog
             global img;
            [file, path] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.jpeg'}, 'Select an Image');
            
            % Check if the user clicked 'Cancel'
            if isequal(file,0) || isequal(path,0)
                return; % Exit the function without doing anything
            end
        
            % Load the image using the selected file path
            imagePath = fullfile(path, file);
            img = imread(imagePath);
        
            % Display the image on the UI
            imshow(img, 'Parent', app.UIAxes);

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global t;
            global data;
            ip=app.IPAddressEditField.Value;
            t=tcpip(ip,50000,'NetworkRole',"client","Timeout",90);
            set(t,'OutputBufferSize',length(data)*8);
            t.ByteOrder = 'littleEndian';
            fopen(t);
            app.Lamp_2.Color='Green';
            app.TransmitDataButton.Enable=1;
        end

        % Button pushed function: TransmitDataButton
        function TransmitDataButtonPushed(app, event)
            global t;  
            global data;
            fwrite(t,data,'double');
            app.Label_2.Text='Data Sent!';
        end

        % Button pushed function: ShowOriginalPhotoButton
        function ShowOriginalPhotoButtonPushed(app, event)
            global img2;
            
            imshow(img2,'Parent',app.UIAxes);
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.ImagePCMClientUIFigure);
            ClientMain
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImagePCMClientUIFigure = uifigure;
            app.ImagePCMClientUIFigure.Name = 'Image PCM - Client';
            app.ImagePCMLabel = uilabel(app.ImagePCMClientUIFigure);
            app.ImagePCMLabel.Text = 'Image-PCM';
            app.GoBackButton = uibutton(app.ImagePCMClientUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.UIAxes = uiaxes(app.ImagePCMClientUIFigure);
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes_2 = uiaxes(app.ImagePCMClientUIFigure);
            app.UIAxes_2.ZTick = [0 0.5 1];
            app.ModulatePhotoButton = uibutton(app.ImagePCMClientUIFigure, 'push');
            app.ModulatePhotoButton.ButtonPushedFcn = createCallbackFcn(app, @ModulatePhotoButtonPushed, true);
            app.ModulatePhotoButton.Text = 'Modulate Photo';
            app.ShowQuantizedPhotoButton = uibutton(app.ImagePCMClientUIFigure, 'push');
            app.ShowQuantizedPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @ShowQuantizedPhotoButtonPushed, true);
            app.ShowQuantizedPhotoButton.Text = 'Show Quantized Photo';
            app.IPAddressEditFieldLabel = uilabel(app.ImagePCMClientUIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.ImagePCMClientUIFigure, 'text');
            app.ConnectButton = uibutton(app.ImagePCMClientUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp_2 = uilamp(app.ImagePCMClientUIFigure);
            app.TransmitDataButton = uibutton(app.ImagePCMClientUIFigure, 'push');
            app.TransmitDataButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitDataButtonPushed, true);
            app.TransmitDataButton.Enable = 'off';
            app.TransmitDataButton.Text = 'Transmit Data';
            app.UploadPhotoButton = uibutton(app.ImagePCMClientUIFigure, 'push');
            app.UploadPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButtonPushed, true);
            app.UploadPhotoButton.Text = 'Upload Photo';
            app.Label = uilabel(app.ImagePCMClientUIFigure);
            app.Label.Text = '';
            app.DataSizeLabel = uilabel(app.ImagePCMClientUIFigure);
            app.DataSizeLabel.Text = 'Data Size:';
            app.Label_2 = uilabel(app.ImagePCMClientUIFigure);
            app.Label_2.Text = '';
            app.ShowOriginalPhotoButton = uibutton(app.ImagePCMClientUIFigure, 'push');
            app.ShowOriginalPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOriginalPhotoButtonPushed, true);
            app.ShowOriginalPhotoButton.Text = 'Show Original Photo';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImagePcmClient

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImagePCMClientUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImagePCMClientUIFigure)
        end
    end
end