classdef ImagePcmServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImagePCMServerUIFigure  matlab.ui.Figure
        ImagePCMLabel           matlab.ui.control.Label
        GoBackButton            matlab.ui.control.Button
        GoOnlineButton          matlab.ui.control.Button
        Lamp_2                  matlab.ui.control.Lamp
        ReceiveDataButton       matlab.ui.control.Button
        SketchDataButton        matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        DataSizeEditFieldLabel  matlab.ui.control.Label
        DataSizeEditField       matlab.ui.control.NumericEditField
        UIAxes                  matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: SketchDataButton
        function SketchDataButtonPushed(app, event)
            global imgobtained;
            imshow(imgobtained,'Parent',app.UIAxes);

        end

        % Button pushed function: GoOnlineButton
        function GoOnlineButtonPushed(app, event)
            global t;
            
            t=tcpip('0.0.0.0',50000,'NetworkRole','server','Timeout',90);
            set(t,'InputBufferSize',80000000);
            t.ByteOrder='littleEndian';
            fopen(t);
            app.ReceiveDataButton.Enable=1;
            app.Lamp_2.Color='Green';
        end

        % Button pushed function: ReceiveDataButton
        function ReceiveDataButtonPushed(app, event)
            global t;  
            global imgobtained;
            ds=app.DataSizeEditField.Value;
            m=fread(t,ds,'double');
            message=m';
            
            stepsize=message(1);
            Vmin=message(2);
            n=message(3);
            wb=message(4);
            indexsize=message(5);
            transmitted=message(6:end);
            
            received=reshape(transmitted,[indexsize wb]);
            indexr=bi2de(received,'left-msb');
            imgreconstructed=uint8(indexr*double(stepsize)+double(Vmin+stepsize/2));
            redrecn=imgreconstructed(1:1:307200);
            greenrecn=imgreconstructed(307201:1:307200+307200);
            bluerecn=imgreconstructed(307200+307200+1:1:end);
            redrec=reshape(redrecn,480,640);
            greenrec=reshape(greenrecn,480,640);
            bluerec=reshape(bluerecn,480,640);
            recimg(:,:,1)=redrec;
            recimg(:,:,2)=greenrec;
            recimg(:,:,3)=bluerec;
            
            fclose(t);
            imgobtained=uint8(recimg);
            
            
            app.Label.Text='Message Received!';
            
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.ImagePCMServerUIFigure);
            ServerMain
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImagePCMServerUIFigure = uifigure;
            app.ImagePCMServerUIFigure.Name = 'Image PCM - Server';
            app.ImagePCMLabel = uilabel(app.ImagePCMServerUIFigure);
            app.ImagePCMLabel.Text = 'Image-PCM';
            app.GoBackButton = uibutton(app.ImagePCMServerUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.GoOnlineButton = uibutton(app.ImagePCMServerUIFigure, 'push');
            app.GoOnlineButton.ButtonPushedFcn = createCallbackFcn(app, @GoOnlineButtonPushed, true);
            app.GoOnlineButton.Text = 'Go Online';
            app.Lamp_2 = uilamp(app.ImagePCMServerUIFigure);
            app.ReceiveDataButton = uibutton(app.ImagePCMServerUIFigure, 'push');
            app.ReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveDataButtonPushed, true);
            app.ReceiveDataButton.Enable = 'off';
            app.ReceiveDataButton.Text = 'Receive Data';
            app.SketchDataButton = uibutton(app.ImagePCMServerUIFigure, 'push');
            app.SketchDataButton.ButtonPushedFcn = createCallbackFcn(app, @SketchDataButtonPushed, true);
            app.SketchDataButton.Text = 'Sketch Data';
            app.Label = uilabel(app.ImagePCMServerUIFigure);
            app.Label.Text = '';
            app.DataSizeEditFieldLabel = uilabel(app.ImagePCMServerUIFigure);
            app.DataSizeEditFieldLabel.Text = 'Data Size';
            app.DataSizeEditField = uieditfield(app.ImagePCMServerUIFigure, 'numeric');
            app.UIAxes = uiaxes(app.ImagePCMServerUIFigure);
            app.UIAxes.ZTick = [0 0.5 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImagePcmServer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImagePCMServerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImagePCMServerUIFigure)
        end
    end
end