classdef MainMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CCE414LLabel               matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        DigitalCommunicationLabel  matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        ContinueButton             matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('aust.jpeg','Parent',app.UIAxes_3);
            imshow('foe.jpeg','Parent',app.UIAxes_2);
            imshow('C:\Users\SmartPc\Desktop\digital com-project/antt.jpeg','Parent',app.UIAxes);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            o=app.Switch.Value;
             switch o
                 case "Server"
                    ServerMain
                    close(app.UIFigure);
                 case "Client"
                    ClientMain
                    close(app.UIFigure);
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.CCE414LLabel = uilabel(app.UIFigure);
            app.CCE414LLabel.Text = 'CCE 414L  ';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.DigitalCommunicationLabel = uilabel(app.UIFigure);
            app.DigitalCommunicationLabel.Text = '          Digital Communication ';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Server', 'Client'};
            app.Switch.Value = 'Server';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainMenu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end