classdef ServerMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ServerUIFigure                matlab.ui.Figure
        ServerLabel                   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        SineWaveCheckBox              matlab.ui.control.CheckBox
        ImageCheckBox                 matlab.ui.control.CheckBox
        AudioCheckBox                 matlab.ui.control.CheckBox
        GoBackButton                  matlab.ui.control.Button
        PleaseSelectModulatingTypeSwitchLabel  matlab.ui.control.Label
        PleaseSelectModulatingTypeSwitch  matlab.ui.control.Switch
        PleaseSelectMessageTypeLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ContinueButton                matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('C:\Users\SmartPc\Desktop\digital com-project/sineee.jpeg','Parent',app.UIAxes);
            imshow('C:\Users\SmartPc\Desktop\digital com-project/soundd.png','Parent',app.UIAxes_2);
            imshow('C:\Users\SmartPc\Desktop\digital com-project/imagee.jpeg','Parent',app.UIAxes_3);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            o=app.PleaseSelectModulatingTypeSwitch.Value;
            switch o
                case "PCM"
                    if(app.ImageCheckBox.Value==true)
                        ImagePcmServer
                        close(app.ServerUIFigure);
                        
                    elseif(app.SineWaveCheckBox.Value==true)
                        SinePcmServer
                        close(app.ServerUIFigure);
                        
                    elseif(app.AudioCheckBox.Value==true)
                        AudioPcmServer
                        close(app.ServerUIFigure);
                    end
                    
                case "DPCM"
                    if(app.ImageCheckBox.Value==true)
                        ImageDpcmServer
                        close(app.ServerUIFigure);
                        
                    elseif(app.SineWaveCheckBox.Value==true)
                        SineDpcmServer
                        close(app.ServerUIFigure);
                        
                    elseif(app.AudioCheckBox.Value==true)
                        AudioDpcmServer
                        close(app.ServerUIFigure);
                    end
            end
        end

        % Value changed function: SineWaveCheckBox
        function SineWaveCheckBoxValueChanged(app, event)
            value = app.SineWaveCheckBox.Value;
            
            if(value==true)
                app.ImageCheckBox.Value=false;
                app.AudioCheckBox.Value=false;
                app.ContinueButton.Enable='on';
                
            elseif(app.SineWaveCheckBox.Value==false && app.ImageCheckBox.Value==false && app.AudioCheckBox.Value==false)
                app.ContinueButton.Enable='off';
                
            end
        
        end

        % Value changed function: ImageCheckBox
        function ImageCheckBoxValueChanged(app, event)
            value = app.ImageCheckBox.Value;
            
            if(value==true)
                app.SineWaveCheckBox.Value=false;
                app.AudioCheckBox.Value=false;
                app.ContinueButton.Enable='on';
                
            elseif(app.SineWaveCheckBox.Value==false && app.ImageCheckBox.Value==false && app.AudioCheckBox.Value==false)
                app.ContinueButton.Enable='off';
                
            end
        end

        % Value changed function: AudioCheckBox
        function AudioCheckBoxValueChanged(app, event)
            value = app.AudioCheckBox.Value;
            
            if(value==true)
                app.ImageCheckBox.Value=false;
                app.SineWaveCheckBox.Value=false;
                app.ContinueButton.Enable='on';
                
            elseif(app.SineWaveCheckBox.Value==false && app.ImageCheckBox.Value==false && app.AudioCheckBox.Value==false)
                app.ContinueButton.Enable='off';
                
            end
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            MainMenu
            close(app.ServerUIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ServerUIFigure = uifigure;
            app.ServerUIFigure.Name = 'Server';
            app.ServerLabel = uilabel(app.ServerUIFigure);
            app.ServerLabel.Text = 'Server ';
            app.UIAxes = uiaxes(app.ServerUIFigure);
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes_2 = uiaxes(app.ServerUIFigure);
            app.UIAxes_3 = uiaxes(app.ServerUIFigure);
            app.SineWaveCheckBox = uicheckbox(app.ServerUIFigure);
            app.SineWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SineWaveCheckBoxValueChanged, true);
            app.SineWaveCheckBox.Text = 'Sine Wave';
            app.ImageCheckBox = uicheckbox(app.ServerUIFigure);
            app.ImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageCheckBoxValueChanged, true);
            app.ImageCheckBox.Text = 'Image';
            app.AudioCheckBox = uicheckbox(app.ServerUIFigure);
            app.AudioCheckBox.ValueChangedFcn = createCallbackFcn(app, @AudioCheckBoxValueChanged, true);
            app.AudioCheckBox.Text = 'Audio';
            app.GoBackButton = uibutton(app.ServerUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.PleaseSelectModulatingTypeSwitchLabel = uilabel(app.ServerUIFigure);
            app.PleaseSelectModulatingTypeSwitchLabel.Text = 'Please Select Modulating Type';
            app.PleaseSelectModulatingTypeSwitch = uiswitch(app.ServerUIFigure, 'slider');
            app.PleaseSelectModulatingTypeSwitch.Items = {'PCM', 'DPCM'};
            app.PleaseSelectModulatingTypeSwitch.Value = 'PCM';
            app.PleaseSelectMessageTypeLabel = uilabel(app.ServerUIFigure);
            app.PleaseSelectMessageTypeLabel.Text = 'Please Select Message Type';
            app.Label = uilabel(app.ServerUIFigure);
            app.Label.Text = '__________________________________________________________________________________________________________________';
            app.ContinueButton = uibutton(app.ServerUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ServerMain

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ServerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ServerUIFigure)
        end
    end
end