classdef SineDpcmClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SineDPCMClientUIFigure         matlab.ui.Figure
        SineWaveDPCMLabel              matlab.ui.control.Label
        GoBackButton                   matlab.ui.control.Button
        IPAddressEditFieldLabel        matlab.ui.control.Label
        IPAddressEditField             matlab.ui.control.EditField
        ConnectButton                  matlab.ui.control.Button
        Lamp_2                         matlab.ui.control.Lamp
        TransmitDataButton             matlab.ui.control.Button
        AmplitudeEditFieldLabel        matlab.ui.control.Label
        AmplitudeEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel       matlab.ui.control.Label
        PhaseShiftEditField            matlab.ui.control.NumericEditField
        SamplingFrequencyLabel         matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        QuantizationBitsDropDownLabel  matlab.ui.control.Label
        QuantizationBitsDropDown       matlab.ui.control.DropDown
        Switch                         matlab.ui.control.Switch
        Lamp                           matlab.ui.control.Lamp
        OptimalLabel                   matlab.ui.control.Label
        ModulateButton                 matlab.ui.control.Button
        SketchOriginalButton           matlab.ui.control.Button
        SketchQuantizedButton          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        OrderEditFieldLabel            matlab.ui.control.Label
        OrderEditField                 matlab.ui.control.NumericEditField
        DataSizeLabel                  matlab.ui.control.Label
        QerrorLabel                    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: ModulateButton
        function ModulateButtonPushed(app, event)
            global opt;
            global m;
            global mq;
            global time;
            global data;
            o=app.QuantizationBitsDropDown.Value;
            A=app.AmplitudeEditField.Value;
            F=app.FrequencyEditField.Value;
            p=app.PhaseShiftEditField.Value;
            fs=app.SamplingFrequencyEditField.Value;
            app.Label.Text='Modulating...';
            order=app.OrderEditField.Value;
            
            if(order==0)
                order=1;
            end
            switch o
                case "2"
                    n=2;
                case "4"
                    n=4;
                case "8"
                    n=8;
                case "16"
                    n=16;
            end
            
            L=2^n;
            
            time=0:1/fs:F/fs;
            m=A*cos(2*pi*F*time+p);
            
            if(opt==1)
                
                primitive_codebook=linspace(-1,1,L);
                [predictor,codebook,partition]=dpcmopt(m,order,primitive_codebook);
                [index,mq]=dpcmenco(m,codebook,partition,predictor);
            elseif(opt==0)
               partition=linspace(-1,1,L-1);
                codebook=linspace(-1,1,L);
                predictor=[0,1];
                [index,mq]=dpcmenco(m,codebook,partition,predictor);
            end
            Qerror=mean((m-mq).^2);
            app.Label_3.Text=num2str(Qerror);
            
            codebooksize=length(codebook);
            predictorsize=length(predictor);
            indexsize=length(index);
            
            data=[F fs codebooksize predictorsize indexsize index codebook predictor];
            DataSize=num2str(length(data));
            app.Label.Text=DataSize;

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            global opt;
            if(value=="Optimal")
                app.Lamp.Color="Green";
                app.Lamp.Enable=true;
                opt=1;
                app.OrderEditField.Enable=1;
                
            elseif(value~="Optimal")
                app.Lamp.Enable=false;
                app.Lamp.Color="White";
                opt=0;
                app.OrderEditField.Enable=0;

            end
        end

        % Button pushed function: SketchOriginalButton
        function SketchOriginalButtonPushed(app, event)
            global m;
            global time;
            plot(app.UIAxes, time, m);
        end

        % Button pushed function: SketchQuantizedButton
        function SketchQuantizedButtonPushed(app, event)
            global mq;
            global time;
            plot(app.UIAxes,time,mq);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global t;
            ip=app.IPAddressEditField.Value;
            t=tcpip(ip,50000,'NetworkRole',"client","Timeout",90);
            set(t,'OutputBufferSize',8000000);
            t.ByteOrder = 'littleEndian';
            fopen(t);
            app.Lamp_2.Color='Green';
            app.TransmitDataButton.Enable=1;
        end

        % Button pushed function: TransmitDataButton
        function TransmitDataButtonPushed(app, event)
            global t;  
            global data;
            fwrite(t,data,'double');
            app.Label_2.Text='Data Sent!';
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.SineDPCMClientUIFigure);
            ClientMain
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SineDPCMClientUIFigure = uifigure;
            app.SineDPCMClientUIFigure.Name = 'Image DPCM - Client';
            app.SineWaveDPCMLabel = uilabel(app.SineDPCMClientUIFigure);
            app.SineWaveDPCMLabel.Text = 'Sine Wave-DPCM';
            app.GoBackButton = uibutton(app.SineDPCMClientUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.IPAddressEditFieldLabel = uilabel(app.SineDPCMClientUIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.SineDPCMClientUIFigure, 'text');
            app.ConnectButton = uibutton(app.SineDPCMClientUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp_2 = uilamp(app.SineDPCMClientUIFigure);
            app.TransmitDataButton = uibutton(app.SineDPCMClientUIFigure, 'push');
            app.TransmitDataButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitDataButtonPushed, true);
            app.TransmitDataButton.Enable = 'off';
            app.TransmitDataButton.Text = 'Transmit Data';
            app.AmplitudeEditFieldLabel = uilabel(app.SineDPCMClientUIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SineDPCMClientUIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.SineDPCMClientUIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SineDPCMClientUIFigure, 'numeric');
            app.PhaseShiftEditFieldLabel = uilabel(app.SineDPCMClientUIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.SineDPCMClientUIFigure, 'numeric');
            app.SamplingFrequencyLabel = uilabel(app.SineDPCMClientUIFigure);
            app.SamplingFrequencyLabel.Text = {'Sampling'; 'Frequency'};
            app.SamplingFrequencyEditField = uieditfield(app.SineDPCMClientUIFigure, 'numeric');
            app.QuantizationBitsDropDownLabel = uilabel(app.SineDPCMClientUIFigure);
            app.QuantizationBitsDropDownLabel.Text = {'Quantization'; 'Bits'};
            app.QuantizationBitsDropDown = uidropdown(app.SineDPCMClientUIFigure);
            app.QuantizationBitsDropDown.Items = {'2', '4', '8', '16'};
            app.QuantizationBitsDropDown.Value = '2';
            app.Switch = uiswitch(app.SineDPCMClientUIFigure, 'slider');
            app.Switch.Items = {'', 'Optimal'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '';
            app.Lamp = uilamp(app.SineDPCMClientUIFigure);
            app.Lamp.Enable = 'off';
            app.OptimalLabel = uilabel(app.SineDPCMClientUIFigure);
            app.OptimalLabel.Text = 'Optimal';
            app.ModulateButton = uibutton(app.SineDPCMClientUIFigure, 'push');
            app.ModulateButton.ButtonPushedFcn = createCallbackFcn(app, @ModulateButtonPushed, true);
            app.ModulateButton.Text = 'Modulate';
            app.SketchOriginalButton = uibutton(app.SineDPCMClientUIFigure, 'push');
            app.SketchOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @SketchOriginalButtonPushed, true);
            app.SketchOriginalButton.Text = 'Sketch Original';
            app.SketchQuantizedButton = uibutton(app.SineDPCMClientUIFigure, 'push');
            app.SketchQuantizedButton.ButtonPushedFcn = createCallbackFcn(app, @SketchQuantizedButtonPushed, true);
            app.SketchQuantizedButton.Text = 'Sketch Quantized';
            app.UIAxes = uiaxes(app.SineDPCMClientUIFigure);
            app.Label = uilabel(app.SineDPCMClientUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.SineDPCMClientUIFigure);
            app.Label_2.Text = '';
            app.OrderEditFieldLabel = uilabel(app.SineDPCMClientUIFigure);
            app.OrderEditFieldLabel.Enable = 'off';
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.SineDPCMClientUIFigure, 'numeric');
            app.OrderEditField.Enable = 'off';
            app.DataSizeLabel = uilabel(app.SineDPCMClientUIFigure);
            app.DataSizeLabel.Text = 'Data Size:';
            app.QerrorLabel = uilabel(app.SineDPCMClientUIFigure);
            app.QerrorLabel.Text = 'Qerror: ';
            app.Label_3 = uilabel(app.SineDPCMClientUIFigure);
            app.Label_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SineDpcmClient

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SineDPCMClientUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SineDPCMClientUIFigure)
        end
    end
end