classdef SineDpcmServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SineDPCMServerUIFigure  matlab.ui.Figure
        SineWaveDPCMLabel       matlab.ui.control.Label
        GoBackButton            matlab.ui.control.Button
        GoOnlineButton          matlab.ui.control.Button
        Lamp_2                  matlab.ui.control.Lamp
        ReceiveDataButton       matlab.ui.control.Button
        SketchDataButton        matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        DataSizeEditFieldLabel  matlab.ui.control.Label
        DataSizeEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: SketchDataButton
        function SketchDataButtonPushed(app, event)
            global mreconstructed;
            global time;
            time=time(1:length(mreconstructed));
            plot(app.UIAxes, time, mreconstructed);
        end

        % Button pushed function: GoOnlineButton
        function GoOnlineButtonPushed(app, event)
            global t;
            
            t=tcpip('0.0.0.0',50000,'NetworkRole','server','Timeout',90);
            set(t,'InputBufferSize',8000000);
            t.ByteOrder='littleEndian';
            fopen(t);
            app.ReceiveDataButton.Enable=1;
            app.Lamp_2.Color='Green';
        end

        % Button pushed function: ReceiveDataButton
        function ReceiveDataButtonPushed(app, event)
            global t;  
            global time;
            global mreconstructed;
            ds=app.DataSizeEditField.Value;
            message=fread(t,ds,'double');
       
            F=message(1);
            fs=message(2);
            codebooksize=message(3);
            predictorsize=message(4);
            indexsize=message(5);
            message1=message(6:end);
            index=message1(1:indexsize);    
            codebook=message1(indexsize+1:indexsize+codebooksize);
            predictor=message1(indexsize+1+codebooksize: end);
            time=0:1/fs:4*F;
            mreconstructed=dpcmdeco(index',codebook,predictor);
            
            fclose(t);
            app.Label.Text='Message Received!';
            
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.SineDPCMServerUIFigure);
            ServerMain
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SineDPCMServerUIFigure = uifigure;
            app.SineDPCMServerUIFigure.Name = 'Image DPCM - Server';
            app.SineWaveDPCMLabel = uilabel(app.SineDPCMServerUIFigure);
            app.SineWaveDPCMLabel.Text = 'Sine Wave DPCM';
            app.GoBackButton = uibutton(app.SineDPCMServerUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.GoOnlineButton = uibutton(app.SineDPCMServerUIFigure, 'push');
            app.GoOnlineButton.ButtonPushedFcn = createCallbackFcn(app, @GoOnlineButtonPushed, true);
            app.GoOnlineButton.Text = 'Go Online';
            app.Lamp_2 = uilamp(app.SineDPCMServerUIFigure);
            app.ReceiveDataButton = uibutton(app.SineDPCMServerUIFigure, 'push');
            app.ReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveDataButtonPushed, true);
            app.ReceiveDataButton.Enable = 'off';
            app.ReceiveDataButton.Text = 'Receive Data';
            app.SketchDataButton = uibutton(app.SineDPCMServerUIFigure, 'push');
            app.SketchDataButton.ButtonPushedFcn = createCallbackFcn(app, @SketchDataButtonPushed, true);
            app.SketchDataButton.Text = 'Sketch Data';
            app.UIAxes = uiaxes(app.SineDPCMServerUIFigure);
            app.Label = uilabel(app.SineDPCMServerUIFigure);
            app.Label.Text = '';
            app.DataSizeEditFieldLabel = uilabel(app.SineDPCMServerUIFigure);
            app.DataSizeEditFieldLabel.Text = 'Data Size';
            app.DataSizeEditField = uieditfield(app.SineDPCMServerUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = SineDpcmServer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SineDPCMServerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SineDPCMServerUIFigure)
        end
    end
end