classdef SinePcmClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SinePCMClientUIFigure          matlab.ui.Figure
        SineWavePCMLabel               matlab.ui.control.Label
        GoBackButton                   matlab.ui.control.Button
        IPAddressEditFieldLabel        matlab.ui.control.Label
        IPAddressEditField             matlab.ui.control.EditField
        ConnectButton                  matlab.ui.control.Button
        Lamp_2                         matlab.ui.control.Lamp
        TransmitDataButton             matlab.ui.control.Button
        AmplitudeEditFieldLabel        matlab.ui.control.Label
        AmplitudeEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel       matlab.ui.control.Label
        PhaseShiftEditField            matlab.ui.control.NumericEditField
        SamplingFrequencyLabel         matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        QuantizationBitsDropDownLabel  matlab.ui.control.Label
        QuantizationBitsDropDown       matlab.ui.control.DropDown
        Switch                         matlab.ui.control.Switch
        Lamp                           matlab.ui.control.Lamp
        OptimalLabel                   matlab.ui.control.Label
        ModulateButton                 matlab.ui.control.Button
        SketchOriginalButton           matlab.ui.control.Button
        SketchQuantizedButton          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        DataSizeLabel                  matlab.ui.control.Label
        QerrorLabel                    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: ModulateButton
        function ModulateButtonPushed(app, event)
     global opt;
global m;
global mq;
global time;
global data;

o = app.QuantizationBitsDropDown.Value;
A = app.AmplitudeEditField.Value;
F = app.FrequencyEditField.Value;
p = app.PhaseShiftEditField.Value;
fs = app.SamplingFrequencyEditField.Value;
app.Label.Text = 'Modulating...';

switch o
    case "2"
        opt = 1;  % Set opt based on the selected quantization bits
    case "4"
        opt = 0;  % Set opt based on the selected quantization bits
    case "8"
        opt = 1;  % Set opt based on the selected quantization bits
    case "16"
        opt = 0;  % Set opt based on the selected quantization bits
    otherwise
        error('Unsupported quantization bits.');
end

L = 2^str2double(o);  % Convert quantization bits to numerical value

time = 0:1/fs:F/fs;
m = A * cos(2 * pi * F * time + p);

Vmin = min(m);
Vmax = max(m);
stepsize = (Vmax - Vmin) / L;

if opt == 1
    primitive_codebook = Vmin - stepsize / 2 : stepsize : Vmax + stepsize / 2;
    [partition, codebook] = lloyds(m, primitive_codebook);
elseif opt == 0
    partition = Vmin : stepsize : Vmax;
    codebook = Vmin - stepsize / 2 : stepsize : Vmax + stepsize / 2;
else
    error('Unsupported option for opt.');
end

[index, mq] = quantiz(m, partition, codebook);
Qerror = mean((m - mq).^2);
app.Label_3.Text = num2str(Qerror);

i_non_zero = find(index ~= 0);
index(i_non_zero) = index(i_non_zero) - 1;

max_index_value = max(index);
num_bits_required = ceil(log2(max_index_value + 1));

b = de2bi(index, num_bits_required, 'left-msb');

if numel(b) == length(index) * num_bits_required
    transmitted = reshape(b', [1, length(index) * num_bits_required]);
    data = [str2double(o), stepsize, Vmin, fs, F, length(index), transmitted];
    DataSize = num2str(length(data));
    app.Label.Text = DataSize;
else
    error('Mismatch in the number of elements during reshaping.');
end

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            global opt;
            if(value=="Optimal")
                app.Lamp.Color="Green";
                app.Lamp.Enable=true;
                opt=1;
                
            elseif(value~="Optimal")
                app.Lamp.Enable=false;
                app.Lamp.Color="White";
                opt=0;
            end
        end

        % Button pushed function: SketchOriginalButton
        function SketchOriginalButtonPushed(app, event)
            global m;
            global time;
            plot(app.UIAxes, time, m);
        end

        % Button pushed function: SketchQuantizedButton
        function SketchQuantizedButtonPushed(app, event)
            global mq;
            global time;
            plot(app.UIAxes,time,mq);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
          global t;
ip = app.IPAddressEditField.Value;  % Ensure this contains the correct server IP
try
    disp(['Attempting to connect to server at ', ip, '...']);
    
    % Create the client TCP/IP object
    t = tcpip(ip, 50000, 'NetworkRole', 'client', 'Timeout', 90);
    set(t, 'OutputBufferSize', 8000000);
    t.ByteOrder = 'littleEndian';
    
    % Open the TCP/IP connection
    fopen(t);
    
    disp('Client connected successfully.');
    
    % Update the app UI to indicate the client is connected
    app.Lamp_2.Color = 'Green';
    app.TransmitDataButton.Enable = 1;
catch ME
    % Display error message if the client connection fails
    disp(['Client connection error: ', ME.message]);
    app.Lamp_2.Color = 'Red';
    
    % Check if the connection was timed out
    if contains(ME.message, 'Connection timed out')
        disp('Connection timed out. Make sure the server is running and reachable.');
    end
end

% Ensure the connection is closed gracefully on exit
cleanup = onCleanup(@() fclose(t));

        end

        % Button pushed function: TransmitDataButton
        function TransmitDataButtonPushed(app, event)
            global t;  
            global data;
            fwrite(t,data,'double');
            app.Label_2.Text='Data Sent!';
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.SinePCMClientUIFigure);
            ClientMain
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SinePCMClientUIFigure = uifigure;
            app.SinePCMClientUIFigure.Name = 'Image PCM - Client';
            app.SineWavePCMLabel = uilabel(app.SinePCMClientUIFigure);
            app.SineWavePCMLabel.Text = 'Sine Wave-PCM';
            app.GoBackButton = uibutton(app.SinePCMClientUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.IPAddressEditFieldLabel = uilabel(app.SinePCMClientUIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.SinePCMClientUIFigure, 'text');
            app.ConnectButton = uibutton(app.SinePCMClientUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp_2 = uilamp(app.SinePCMClientUIFigure);
            app.TransmitDataButton = uibutton(app.SinePCMClientUIFigure, 'push');
            app.TransmitDataButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitDataButtonPushed, true);
            app.TransmitDataButton.Enable = 'off';
            app.TransmitDataButton.Text = 'Transmit Data';
            app.AmplitudeEditFieldLabel = uilabel(app.SinePCMClientUIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SinePCMClientUIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.SinePCMClientUIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SinePCMClientUIFigure, 'numeric');
            app.PhaseShiftEditFieldLabel = uilabel(app.SinePCMClientUIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.SinePCMClientUIFigure, 'numeric');
            app.SamplingFrequencyLabel = uilabel(app.SinePCMClientUIFigure);
            app.SamplingFrequencyLabel.Text = {'Sampling'; 'Frequency'};
            app.SamplingFrequencyEditField = uieditfield(app.SinePCMClientUIFigure, 'numeric');
            app.QuantizationBitsDropDownLabel = uilabel(app.SinePCMClientUIFigure);
            app.QuantizationBitsDropDownLabel.Text = {'Quantization'; 'Bits'};
            app.QuantizationBitsDropDown = uidropdown(app.SinePCMClientUIFigure);
            app.QuantizationBitsDropDown.Items = {'2', '4', '8', '16'};
            app.QuantizationBitsDropDown.Value = '2';
            app.Switch = uiswitch(app.SinePCMClientUIFigure, 'slider');
            app.Switch.Items = {'', 'Optimal'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '';
            app.Lamp = uilamp(app.SinePCMClientUIFigure);
            app.Lamp.Enable = 'off';
            app.OptimalLabel = uilabel(app.SinePCMClientUIFigure);
            app.OptimalLabel.Text = 'Optimal';
            app.ModulateButton = uibutton(app.SinePCMClientUIFigure, 'push');
            app.ModulateButton.ButtonPushedFcn = createCallbackFcn(app, @ModulateButtonPushed, true);
            app.ModulateButton.Text = 'Modulate';
            app.SketchOriginalButton = uibutton(app.SinePCMClientUIFigure, 'push');
            app.SketchOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @SketchOriginalButtonPushed, true);
            app.SketchOriginalButton.Text = 'Sketch Original';
            app.SketchQuantizedButton = uibutton(app.SinePCMClientUIFigure, 'push');
            app.SketchQuantizedButton.ButtonPushedFcn = createCallbackFcn(app, @SketchQuantizedButtonPushed, true);
            app.SketchQuantizedButton.Text = 'Sketch Quantized';
            app.UIAxes = uiaxes(app.SinePCMClientUIFigure);
            app.Label = uilabel(app.SinePCMClientUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.SinePCMClientUIFigure);
            app.Label_2.Text = '';
            app.DataSizeLabel = uilabel(app.SinePCMClientUIFigure);
            app.DataSizeLabel.Text = 'Data Size:';
            app.QerrorLabel = uilabel(app.SinePCMClientUIFigure);
            app.QerrorLabel.Text = 'Qerror: ';
            app.Label_3 = uilabel(app.SinePCMClientUIFigure);
            app.Label_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SinePcmClient

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SinePCMClientUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SinePCMClientUIFigure)
        end
    end
end