classdef SinePcmServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SinePCMServerUIFigure   matlab.ui.Figure
        SineWavePCMLabel        matlab.ui.control.Label
        GoBackButton            matlab.ui.control.Button
        GoOnlineButton          matlab.ui.control.Button
        Lamp_2                  matlab.ui.control.Lamp
        ReceiveDataButton       matlab.ui.control.Button
        SketchDataButton        matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        DataSizeEditFieldLabel  matlab.ui.control.Label
        DataSizeEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: SketchDataButton
        function SketchDataButtonPushed(app, event)
            global mreconstructed;
            global time;
            time=time(1:length(mreconstructed));
            plot(app.UIAxes, time, mreconstructed);
        end

        % Button pushed function: GoOnlineButton
        function GoOnlineButtonPushed(app, event)
        global t;
try
    disp('Starting server setup...');
    
    % Create the server TCP/IP object
    t = tcpip('0.0.0.0', 50000, 'NetworkRole', 'server', 'Timeout', 90);
    set(t, 'InputBufferSize', 8000000);
    t.ByteOrder = 'littleEndian';
    
    % Open the TCP/IP connection
    fopen(t);
    
    % Update the app UI to indicate the server is ready
    app.ReceiveDataButton.Enable = 1;
    app.Lamp_2.Color = 'Green';
    
    disp('Server setup successful. Waiting for client connection...');
catch ME
    disp(['Server connection error: ', ME.message]);
    app.Lamp_2.Color = 'Red';
end

% Ensure the connection is closed gracefully on exit
cleanup = onCleanup(@() fclose(t));

        end

        % Button pushed function: ReceiveDataButton
        function ReceiveDataButtonPushed(app, event)
            global t;  
            global time;
            global mreconstructed;
            ds=app.DataSizeEditField.Value;
            message=fread(t,ds,'double');
       
            n=message(1);
            stepsize=message(2);
            Vmin=message(3);
            fs=message(4);
            F=message(5);
            index=message(6);
            transmitted=message(7:end);
            time=0:1/fs:4*F;
            received=reshape(transmitted,[index n]);
            indexr=bi2de(received,'left-msb');
            mreconstructed=indexr*stepsize+Vmin+stepsize/2;
            disp(length(mreconstructed));
            disp(length(time));
            
            fclose(t);
            app.Label.Text='Message Received!';
            
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            close(app.SinePCMServerUIFigure);
            ServerMain
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SinePCMServerUIFigure = uifigure;
            app.SinePCMServerUIFigure.Name = 'Image PCM - Server';
            app.SineWavePCMLabel = uilabel(app.SinePCMServerUIFigure);
            app.SineWavePCMLabel.Text = 'Sine Wave-PCM';
            app.GoBackButton = uibutton(app.SinePCMServerUIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.GoOnlineButton = uibutton(app.SinePCMServerUIFigure, 'push');
            app.GoOnlineButton.ButtonPushedFcn = createCallbackFcn(app, @GoOnlineButtonPushed, true);
            app.GoOnlineButton.Text = 'Go Online';
            app.Lamp_2 = uilamp(app.SinePCMServerUIFigure);
            app.ReceiveDataButton = uibutton(app.SinePCMServerUIFigure, 'push');
            app.ReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveDataButtonPushed, true);
            app.ReceiveDataButton.Enable = 'off';
            app.ReceiveDataButton.Text = 'Receive Data';
            app.SketchDataButton = uibutton(app.SinePCMServerUIFigure, 'push');
            app.SketchDataButton.ButtonPushedFcn = createCallbackFcn(app, @SketchDataButtonPushed, true);
            app.SketchDataButton.Text = 'Sketch Data';
            app.UIAxes = uiaxes(app.SinePCMServerUIFigure);
            app.Label = uilabel(app.SinePCMServerUIFigure);
            app.Label.Text = '';
            app.DataSizeEditFieldLabel = uilabel(app.SinePCMServerUIFigure);
            app.DataSizeEditFieldLabel.Text = 'Data Size';
            app.DataSizeEditField = uieditfield(app.SinePCMServerUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = SinePcmServer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SinePCMServerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SinePCMServerUIFigure)
        end
    end
end